classdef RITE_Dos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RITEDosUIFigure                 matlab.ui.Figure
        RITEDosLabel                    matlab.ui.control.Label
        RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel  matlab.ui.control.Label
        CTSimulatorPanel                matlab.ui.container.Panel
        LoadPathEditFieldLabel          matlab.ui.control.Label
        CTSim_LoadPathEditField         matlab.ui.control.EditField
        CTSim_NewCommissionButton       matlab.ui.control.StateButton
        CTSim_EditButton                matlab.ui.control.StateButton
        CTSim_LoadButton                matlab.ui.control.StateButton
        UnitNameEditFieldLabel          matlab.ui.control.Label
        CTSim_UnitNameEditField         matlab.ui.control.EditField
        LinearAcceleratorPanel          matlab.ui.container.Panel
        LoadPathEditField_2Label        matlab.ui.control.Label
        Linac_LoadPathEditField         matlab.ui.control.EditField
        Linac_NewCommissionButton       matlab.ui.control.StateButton
        Linac_EditButton                matlab.ui.control.StateButton
        Linac_LoadButton                matlab.ui.control.StateButton
        UnitNameEditField_2Label        matlab.ui.control.Label
        Linac_UnitNameEditField         matlab.ui.control.EditField
        CommissionedEnergiesDropDownLabel  matlab.ui.control.Label
        Linac_CommissionedEnergiesDropDown  matlab.ui.control.DropDown
        Linac_CommissionedEnergiesEditButton  matlab.ui.control.StateButton
        Linac_NewCommissionEnergyButton  matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CTSimulatorEditTab              matlab.ui.container.Tab
        EditCommissionCTSimulatorLabel  matlab.ui.control.Label
        UnitNameEditFieldLabel_2        matlab.ui.control.Label
        CTSim_Edit_UnitNameEditField    matlab.ui.control.EditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        CTSim_Edit_NegRed_SlopeEditField  matlab.ui.control.NumericEditField
        HUREDLabel                      matlab.ui.control.Label
        RelativeElectronDensityREDCurveLabel  matlab.ui.control.Label
        HUValueCurveLabel               matlab.ui.control.Label
        HULabel                         matlab.ui.control.Label
        InterceptEditFieldLabel         matlab.ui.control.Label
        CTSim_Edit_NegRed_InterceptEditField  matlab.ui.control.NumericEditField
        HUREDLabel_4                    matlab.ui.control.Label
        HUValueCurveLabel_2             matlab.ui.control.Label
        HULabel_2                       matlab.ui.control.Label
        SlopeEditField_2Label           matlab.ui.control.Label
        CTSim_Edit_PosRed_SlopeEditField  matlab.ui.control.NumericEditField
        InterceptEditField_2Label       matlab.ui.control.Label
        CTSim_Edit_PosRed_InterceptEditField  matlab.ui.control.NumericEditField
        AirCutoffEditFieldLabel         matlab.ui.control.Label
        CTSim_Edit_AirCutoffEditField   matlab.ui.control.NumericEditField
        HULabel_3                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CTSim_Edit_CreateButton         matlab.ui.control.StateButton
        CTSim_Edit_SaveButton           matlab.ui.control.StateButton
        CTSim_Edit_CancelButton         matlab.ui.control.StateButton
        NotesLabel                      matlab.ui.control.Label
        CTSim_Edit_NotesTextArea        matlab.ui.control.TextArea
        LinacEditTab                    matlab.ui.container.Tab
        EditCommissionLinearAcceleratorLabel  matlab.ui.control.Label
        EPIDDimensionsLabel             matlab.ui.control.Label
        NumberofPixelsLabel             matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        PixelDimensionsLabel            matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Linac_Edit_CreateButton         matlab.ui.control.StateButton
        Linac_Edit_SaveButton           matlab.ui.control.StateButton
        Linac_Edit_CancelButton         matlab.ui.control.StateButton
        UnitNameEditFieldLabel_3        matlab.ui.control.Label
        Linac_Edit_UnitNameEditField    matlab.ui.control.EditField
        CrossplaneLabel                 matlab.ui.control.Label
        Linac_Edit_NumPixelsCrossplaneEditField  matlab.ui.control.NumericEditField
        InplaneLabel                    matlab.ui.control.Label
        Linac_Edit_NumPixelsInplaneEditField  matlab.ui.control.NumericEditField
        CrossplaneLabel_2               matlab.ui.control.Label
        Linac_Edit_PixelDimsCrossplaneEditField  matlab.ui.control.NumericEditField
        InplaneLabel_2                  matlab.ui.control.Label
        Linac_Edit_PixelDimsInplaneEditField  matlab.ui.control.NumericEditField
        LinacDimensionsLabel            matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        SourcetoIsocentreDistanceLabel  matlab.ui.control.Label
        Linac_Edit_SourceToIsocentreEditField  matlab.ui.control.NumericEditField
        EPIDtoIsocentreDistanceLabel    matlab.ui.control.Label
        Linac_Edit_EpidToIsocentreEditField  matlab.ui.control.NumericEditField
        cmLabel_4                       matlab.ui.control.Label
        NotesLabel_2                    matlab.ui.control.Label
        Linac_Edit_NotesTextArea        matlab.ui.control.TextArea
        noteLinacLabel                  matlab.ui.control.Label
        noteLinacLabel_2                matlab.ui.control.Label
        EnergyEditTab                   matlab.ui.container.Tab
        EditCommissionPhotonBeamLabel   matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Beam_Edit_SaveButton            matlab.ui.control.StateButton
        Beam_Edit_CancelButton          matlab.ui.control.StateButton
        NotesLabel_3                    matlab.ui.control.Label
        Beam_Edit_NotesTextArea         matlab.ui.control.TextArea
        MeVLabel                        matlab.ui.control.Label
        BeamEnergyLabel                 matlab.ui.control.Label
        Beam_Edit_BeamEnergyEditField   matlab.ui.control.NumericEditField
        CommissioningStepsLabel         matlab.ui.control.Label
        ChooseFieldSizesPhantomWidthsandPhantomShiftsLabel  matlab.ui.control.Label
        FieldSizeslLabel                matlab.ui.control.Label
        Beam_Edit_FieldSizesEditField   matlab.ui.control.EditField
        FieldscansetasxyzorstartintervalendLabel  matlab.ui.control.Label
        nxncmLabel                      matlab.ui.control.Label
        sizesofthesquarefieldsthecollimatorswillbesettoLabel  matlab.ui.control.Label
        cmLabel_5                       matlab.ui.control.Label
        thewidththicknessofthesolidwaterphantomstobeusedLabel  matlab.ui.control.Label
        PhantomWidthswLabel             matlab.ui.control.Label
        Beam_Edit_PhantomWidthsEditField  matlab.ui.control.EditField
        cmLabel_6                       matlab.ui.control.Label
        theamountthecentreofthesolidwaterphantomswillbeshiftedLabel  matlab.ui.control.Label
        PhantomShiftsdLabel             matlab.ui.control.Label
        Beam_Edit_PhantomShiftsEditField  matlab.ui.control.EditField
        fromisocentred0isashiftupwardsd0isdownwardsLabel  matlab.ui.control.Label
        ChooseandcreatedirectoriestoholdcommissioningdataLabel  matlab.ui.control.Label
        text_step2                      matlab.ui.control.Label
        test_Step2_2                    matlab.ui.control.Label
        test_step2_3                    matlab.ui.control.Label
        CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6  matlab.ui.control.Label
        LoadTissueMaximumRatioDataLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8  matlab.ui.control.Label
        CalculatecommissioningvaluesLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9  matlab.ui.control.Label
        Beam_Edit_CommissioningDataPathEditField  matlab.ui.control.EditField
        Beam_Edit_CommissioningDataPathSelectButton  matlab.ui.control.StateButton
        Beam_Edit_CommissioningDataDirectoriesCreateButton  matlab.ui.control.StateButton
        Label_5                         matlab.ui.control.Label
        Beam_Edit_CommissioningCalculateAndSaveButton  matlab.ui.control.StateButton
        Beam_Edit_TmrDataPathEditField  matlab.ui.control.EditField
        Beam_Edit_TmrDataPathSelectButton  matlab.ui.control.StateButton
        Beam_Edit_TmrDataLoadButton     matlab.ui.control.StateButton
        MULabel_2                       matlab.ui.control.Label
        DeliveredMUsLabel               matlab.ui.control.Label
        Beam_Edit_DeliveredMUsEditField  matlab.ui.control.NumericEditField
        Beam_Edit_RemoveButton          matlab.ui.control.StateButton
        LoadTreatmentPlanningSystemRTFileLabel  matlab.ui.control.Label
        text_step5                      matlab.ui.control.Label
        test_step5_2                    matlab.ui.control.Label
        Beam_Edit_TmrDataPathEditField_2  matlab.ui.control.EditField
        Beam_Edit_TmrDataPathSelectButton_2  matlab.ui.control.StateButton
        Beam_Edit_TmrDataLoadButton_2   matlab.ui.control.StateButton
        PatientDoseCalculationPanel     matlab.ui.container.Panel
        CTDataLoadPathLabel             matlab.ui.control.Label
        DoseCalc_CTDataPathEditField    matlab.ui.control.EditField
        DoseCalc_CTDataPathLoadButton   matlab.ui.control.StateButton
        DoseCalc_CalculateButton        matlab.ui.control.StateButton
        Label_6                         matlab.ui.control.Label
        EPIDDataLoadPathLabel           matlab.ui.control.Label
        DoseCalc_EpidPathEditField      matlab.ui.control.EditField
        DoseCalc_EpidPathLoadButton     matlab.ui.control.StateButton
        degreesLabel                    matlab.ui.control.Label
        GantryAngleLabel                matlab.ui.control.Label
        DoseCalc_GantryAngleEditField   matlab.ui.control.NumericEditField
        NumberofFractionsLabel          matlab.ui.control.Label
        DoseCalc_NumFractionsEditField  matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        DrStefanoPecaemailLabel         matlab.ui.control.Label
        DavidDeVriesemailLabel          matlab.ui.control.Label
        ZachWhatmanemailLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        ctSim % loaded CTSim object
        ctSimLoadPath % path where the above object came from
        
        linac % loaded Linac object
        linacLoadPath % path where the above object came from
        
        settings % a Settings object, loaded from the same directory as the app
    end


    methods (Access = private)

        % Value changed function: CTSim_NewCommissionButton
        function CTSim_NewCommissionButtonValueChanged(app, event)
            CTSim_NewCommissionButtonValueCallback(app);            
        end

        % Value changed function: CTSim_EditButton
        function CTSim_EditButtonValueChanged(app, event)
            CTSim_EditButtonValueCallback(app);
        end

        % Value changed function: CTSim_LoadButton
        function CTSim_LoadButtonValueChanged(app, event)
            CTSim_LoadButtonValueCallback(app);
        end

        % Value changed function: Linac_NewCommissionButton
        function Linac_NewCommissionButtonValueChanged(app, event)
            value = app.Linac_NewCommissionButton.Value;
            
        end

        % Value changed function: Linac_EditButton
        function Linac_EditButtonValueChanged(app, event)
            value = app.Linac_EditButton.Value;
            
        end

        % Value changed function: Linac_LoadButton
        function Linac_LoadButtonValueChanged(app, event)
            value = app.Linac_LoadButton.Value;
            
        end

        % Value changed function: Linac_CommissionedEnergiesEditButton
        function Linac_CommissionedEnergiesEditButtonValueChanged(app, event)
            value = app.Linac_CommissionedEnergiesEditButton.Value;
            
        end

        % Value changed function: Linac_NewCommissionEnergyButton
        function Linac_NewCommissionEnergyButtonValueChanged(app, event)
            value = app.Linac_NewCommissionEnergyButton.Value;
            
        end

        % Value changed function: CTSim_Edit_CreateButton
        function CTSim_Edit_CreateButtonValueChanged(app, event)
            value = app.CTSim_Edit_CreateButton.Value;
            
        end

        % Value changed function: CTSim_Edit_SaveButton
        function CTSim_Edit_SaveButtonValueChanged(app, event)
            value = app.CTSim_Edit_SaveButton.Value;
            
        end

        % Value changed function: CTSim_Edit_CancelButton
        function CTSim_Edit_CancelButtonValueChanged(app, event)
            value = app.CTSim_Edit_CancelButton.Value;
            
        end

        % Value changed function: DoseCalc_CTDataPathLoadButton
        function DoseCalc_CTDataPathLoadButtonValueChanged(app, event)
            value = app.DoseCalc_CTDataPathLoadButton.Value;
            
        end

        % Value changed function: DoseCalc_EpidPathLoadButton
        function DoseCalc_EpidPathLoadButtonValueChanged(app, event)
            value = app.DoseCalc_EpidPathLoadButton.Value;
            
        end

        % Value changed function: DoseCalc_CalculateButton
        function DoseCalc_CalculateButtonValueChanged(app, event)
            value = app.DoseCalc_CalculateButton.Value;
            
        end

        % Value changed function: Linac_Edit_CreateButton
        function Linac_Edit_CreateButtonValueChanged(app, event)
            value = app.Linac_Edit_CreateButton.Value;
            
        end

        % Value changed function: Linac_Edit_SaveButton
        function Linac_Edit_SaveButtonValueChanged(app, event)
            value = app.Linac_Edit_SaveButton.Value;
            
        end

        % Value changed function: Linac_Edit_CancelButton
        function Linac_Edit_CancelButtonValueChanged(app, event)
            value = app.Linac_Edit_CancelButton.Value;
            
        end

        % Value changed function: 
        % Beam_Edit_CommissioningDataPathSelectButton
        function Beam_Edit_CommissioningDataPathSelectButtonValueChanged(app, event)
            value = app.Beam_Edit_CommissioningDataPathSelectButton.Value;
            
        end

        % Value changed function: 
        % Beam_Edit_CommissioningDataDirectoriesCreateButton
        function Beam_Edit_CommissioningDataDirectoriesCreateButtonValueChanged(app, event)
            value = app.Beam_Edit_CommissioningDataDirectoriesCreateButton.Value;
            
        end

        % Value changed function: Beam_Edit_TmrDataPathSelectButton
        function Beam_Edit_TmrDataPathSelectButtonValueChanged(app, event)
            value = app.Beam_Edit_TmrDataPathSelectButton.Value;
            
        end

        % Value changed function: Beam_Edit_TmrDataLoadButton
        function Beam_Edit_TmrDataLoadButtonValueChanged(app, event)
            value = app.Beam_Edit_TmrDataLoadButton.Value;
            
        end

        % Value changed function: Beam_Edit_TmrDataPathSelectButton_2
        function Beam_Edit_TmrDataPathSelectButton_2ValueChanged(app, event)
            value = app.Beam_Edit_TmrDataPathSelectButton_2.Value;
            
        end

        % Value changed function: Beam_Edit_TmrDataLoadButton_2
        function Beam_Edit_TmrDataLoadButton_2ValueChanged(app, event)
            value = app.Beam_Edit_TmrDataLoadButton_2.Value;
            
        end

        % Value changed function: 
        % Beam_Edit_CommissioningCalculateAndSaveButton
        function Beam_Edit_CommissioningCalculateAndSaveButtonValueChanged(app, event)
            value = app.Beam_Edit_CommissioningCalculateAndSaveButton.Value;
            
        end

        % Value changed function: Beam_Edit_SaveButton
        function Beam_Edit_SaveButtonValueChanged(app, event)
            value = app.Beam_Edit_SaveButton.Value;
            
        end

        % Value changed function: Beam_Edit_CancelButton
        function Beam_Edit_CancelButtonValueChanged(app, event)
            value = app.Beam_Edit_CancelButton.Value;
            
        end

        % Value changed function: Beam_Edit_RemoveButton
        function Beam_Edit_RemoveButtonValueChanged(app, event)
            value = app.Beam_Edit_RemoveButton.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RITEDosUIFigure = uifigure;
            app.RITEDosUIFigure.Name = 'RITE-Dos';
            app.RITEDosLabel = uilabel(app.RITEDosUIFigure);
            app.RITEDosLabel.Text = 'RITE-Dos';
            app.RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel = uilabel(app.RITEDosUIFigure);
            app.RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel.Text = 'Radiotherapy In vivo Two-Dimensional EPID Dosimetry';
            app.CTSimulatorPanel = uipanel(app.RITEDosUIFigure);
            app.CTSimulatorPanel.Title = 'CT Simulator';
            app.LoadPathEditFieldLabel = uilabel(app.CTSimulatorPanel);
            app.LoadPathEditFieldLabel.Text = 'Load Path:';
            app.CTSim_LoadPathEditField = uieditfield(app.CTSimulatorPanel, 'text');
            app.CTSim_LoadPathEditField.Editable = 'off';
            app.CTSim_NewCommissionButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_NewCommissionButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_NewCommissionButtonValueChanged, true);
            app.CTSim_NewCommissionButton.Text = 'New/Commission';
            app.CTSim_EditButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_EditButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_EditButtonValueChanged, true);
            app.CTSim_EditButton.Text = 'Edit';
            app.CTSim_LoadButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_LoadButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_LoadButtonValueChanged, true);
            app.CTSim_LoadButton.Text = 'Load...';
            app.UnitNameEditFieldLabel = uilabel(app.CTSimulatorPanel);
            app.UnitNameEditFieldLabel.Text = 'Unit Name:';
            app.CTSim_UnitNameEditField = uieditfield(app.CTSimulatorPanel, 'text');
            app.CTSim_UnitNameEditField.Editable = 'off';
            app.LinearAcceleratorPanel = uipanel(app.RITEDosUIFigure);
            app.LinearAcceleratorPanel.Title = 'Linear Accelerator';
            app.LoadPathEditField_2Label = uilabel(app.LinearAcceleratorPanel);
            app.LoadPathEditField_2Label.Text = 'Load Path:';
            app.Linac_LoadPathEditField = uieditfield(app.LinearAcceleratorPanel, 'text');
            app.Linac_LoadPathEditField.Editable = 'off';
            app.Linac_NewCommissionButton = uibutton(app.LinearAcceleratorPanel, 'state');
            app.Linac_NewCommissionButton.ValueChangedFcn = createCallbackFcn(app, @Linac_NewCommissionButtonValueChanged, true);
            app.Linac_NewCommissionButton.Text = 'New/Commission';
            app.Linac_EditButton = uibutton(app.LinearAcceleratorPanel, 'state');
            app.Linac_EditButton.ValueChangedFcn = createCallbackFcn(app, @Linac_EditButtonValueChanged, true);
            app.Linac_EditButton.Text = 'Edit';
            app.Linac_LoadButton = uibutton(app.LinearAcceleratorPanel, 'state');
            app.Linac_LoadButton.ValueChangedFcn = createCallbackFcn(app, @Linac_LoadButtonValueChanged, true);
            app.Linac_LoadButton.Text = 'Load...';
            app.UnitNameEditField_2Label = uilabel(app.LinearAcceleratorPanel);
            app.UnitNameEditField_2Label.Text = 'Unit Name:';
            app.Linac_UnitNameEditField = uieditfield(app.LinearAcceleratorPanel, 'text');
            app.Linac_UnitNameEditField.Editable = 'off';
            app.CommissionedEnergiesDropDownLabel = uilabel(app.LinearAcceleratorPanel);
            app.CommissionedEnergiesDropDownLabel.Text = 'Commissioned Energies:';
            app.Linac_CommissionedEnergiesDropDown = uidropdown(app.LinearAcceleratorPanel);
            app.Linac_CommissionedEnergiesDropDown.Items = {'6 MeV', '15 MeV'};
            app.Linac_CommissionedEnergiesDropDown.Value = '6 MeV';
            app.Linac_CommissionedEnergiesEditButton = uibutton(app.LinearAcceleratorPanel, 'state');
            app.Linac_CommissionedEnergiesEditButton.ValueChangedFcn = createCallbackFcn(app, @Linac_CommissionedEnergiesEditButtonValueChanged, true);
            app.Linac_CommissionedEnergiesEditButton.Text = 'Edit/Delete...';
            app.Linac_NewCommissionEnergyButton = uibutton(app.LinearAcceleratorPanel, 'state');
            app.Linac_NewCommissionEnergyButton.ValueChangedFcn = createCallbackFcn(app, @Linac_NewCommissionEnergyButtonValueChanged, true);
            app.Linac_NewCommissionEnergyButton.Text = 'New/Commission';
            app.Label = uilabel(app.LinearAcceleratorPanel);
            app.Label.Text = '____________________________________________________________________';
            app.TabGroup = uitabgroup(app.RITEDosUIFigure);
            app.CTSimulatorEditTab = uitab(app.TabGroup);
            app.CTSimulatorEditTab.Title = 'CT Simulator Edit';
            app.EditCommissionCTSimulatorLabel = uilabel(app.CTSimulatorEditTab);
            app.EditCommissionCTSimulatorLabel.Text = 'Edit/Commission CT Simulator';
            app.UnitNameEditFieldLabel_2 = uilabel(app.CTSimulatorEditTab);
            app.UnitNameEditFieldLabel_2.Text = 'Unit Name:';
            app.CTSim_Edit_UnitNameEditField = uieditfield(app.CTSimulatorEditTab, 'text');
            app.SlopeEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.SlopeEditFieldLabel.Text = 'Slope:';
            app.CTSim_Edit_NegRed_SlopeEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_NegRed_SlopeEditField.Limits = [0 Inf];
            app.CTSim_Edit_NegRed_SlopeEditField.ValueDisplayFormat = '%11.10g';
            app.HUREDLabel = uilabel(app.CTSimulatorEditTab);
            app.HUREDLabel.Text = 'HU/RED';
            app.RelativeElectronDensityREDCurveLabel = uilabel(app.CTSimulatorEditTab);
            app.RelativeElectronDensityREDCurveLabel.Text = 'Relative Electron Density (RED) Curve:';
            app.HUValueCurveLabel = uilabel(app.CTSimulatorEditTab);
            app.HUValueCurveLabel.Text = '-HU Value Curve:';
            app.HULabel = uilabel(app.CTSimulatorEditTab);
            app.HULabel.Text = 'HU';
            app.InterceptEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.InterceptEditFieldLabel.Text = 'Intercept:';
            app.CTSim_Edit_NegRed_InterceptEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_NegRed_InterceptEditField.ValueDisplayFormat = '%11.10g';
            app.HUREDLabel_4 = uilabel(app.CTSimulatorEditTab);
            app.HUREDLabel_4.Text = 'HU/RED';
            app.HUValueCurveLabel_2 = uilabel(app.CTSimulatorEditTab);
            app.HUValueCurveLabel_2.Text = '+HU Value Curve:';
            app.HULabel_2 = uilabel(app.CTSimulatorEditTab);
            app.HULabel_2.Text = 'HU';
            app.SlopeEditField_2Label = uilabel(app.CTSimulatorEditTab);
            app.SlopeEditField_2Label.Text = 'Slope:';
            app.CTSim_Edit_PosRed_SlopeEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_PosRed_SlopeEditField.Limits = [0 Inf];
            app.CTSim_Edit_PosRed_SlopeEditField.ValueDisplayFormat = '%11.10g';
            app.InterceptEditField_2Label = uilabel(app.CTSimulatorEditTab);
            app.InterceptEditField_2Label.Text = 'Intercept:';
            app.CTSim_Edit_PosRed_InterceptEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_PosRed_InterceptEditField.ValueDisplayFormat = '%11.10g';
            app.AirCutoffEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.AirCutoffEditFieldLabel.Text = 'Air Cutoff:';
            app.CTSim_Edit_AirCutoffEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_AirCutoffEditField.ValueDisplayFormat = '%11.10g';
            app.HULabel_3 = uilabel(app.CTSimulatorEditTab);
            app.HULabel_3.Text = 'HU';
            app.Label_2 = uilabel(app.CTSimulatorEditTab);
            app.Label_2.Text = '__________________________________________________________________________';
            app.CTSim_Edit_CreateButton = uibutton(app.CTSimulatorEditTab, 'state');
            app.CTSim_Edit_CreateButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_Edit_CreateButtonValueChanged, true);
            app.CTSim_Edit_CreateButton.Text = 'Create';
            app.CTSim_Edit_SaveButton = uibutton(app.CTSimulatorEditTab, 'state');
            app.CTSim_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_Edit_SaveButtonValueChanged, true);
            app.CTSim_Edit_SaveButton.Text = 'Save';
            app.CTSim_Edit_CancelButton = uibutton(app.CTSimulatorEditTab, 'state');
            app.CTSim_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_Edit_CancelButtonValueChanged, true);
            app.CTSim_Edit_CancelButton.Text = 'Cancel';
            app.NotesLabel = uilabel(app.CTSimulatorEditTab);
            app.NotesLabel.Text = 'Notes:';
            app.CTSim_Edit_NotesTextArea = uitextarea(app.CTSimulatorEditTab);
            app.LinacEditTab = uitab(app.TabGroup);
            app.LinacEditTab.Title = 'Linac Edit';
            app.EditCommissionLinearAcceleratorLabel = uilabel(app.LinacEditTab);
            app.EditCommissionLinearAcceleratorLabel.Text = 'Edit/Commission Linear Accelerator';
            app.EPIDDimensionsLabel = uilabel(app.LinacEditTab);
            app.EPIDDimensionsLabel.Text = 'EPID Dimensions:';
            app.NumberofPixelsLabel = uilabel(app.LinacEditTab);
            app.NumberofPixelsLabel.Text = 'Number of Pixels:';
            app.cmLabel = uilabel(app.LinacEditTab);
            app.cmLabel.Text = 'cm';
            app.PixelDimensionsLabel = uilabel(app.LinacEditTab);
            app.PixelDimensionsLabel.Text = 'Pixel Dimensions:';
            app.cmLabel_2 = uilabel(app.LinacEditTab);
            app.cmLabel_2.Text = 'cm';
            app.Label_3 = uilabel(app.LinacEditTab);
            app.Label_3.Text = '__________________________________________________________________________';
            app.Linac_Edit_CreateButton = uibutton(app.LinacEditTab, 'state');
            app.Linac_Edit_CreateButton.ValueChangedFcn = createCallbackFcn(app, @Linac_Edit_CreateButtonValueChanged, true);
            app.Linac_Edit_CreateButton.Text = 'Create';
            app.Linac_Edit_SaveButton = uibutton(app.LinacEditTab, 'state');
            app.Linac_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Linac_Edit_SaveButtonValueChanged, true);
            app.Linac_Edit_SaveButton.Text = 'Save';
            app.Linac_Edit_CancelButton = uibutton(app.LinacEditTab, 'state');
            app.Linac_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @Linac_Edit_CancelButtonValueChanged, true);
            app.Linac_Edit_CancelButton.Text = 'Cancel';
            app.UnitNameEditFieldLabel_3 = uilabel(app.LinacEditTab);
            app.UnitNameEditFieldLabel_3.Text = 'Unit Name:';
            app.Linac_Edit_UnitNameEditField = uieditfield(app.LinacEditTab, 'text');
            app.CrossplaneLabel = uilabel(app.LinacEditTab);
            app.CrossplaneLabel.Text = 'Crossplane:';
            app.Linac_Edit_NumPixelsCrossplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_NumPixelsCrossplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_NumPixelsCrossplaneEditField.ValueDisplayFormat = '%11.10g';
            app.InplaneLabel = uilabel(app.LinacEditTab);
            app.InplaneLabel.Text = 'Inplane:';
            app.Linac_Edit_NumPixelsInplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_NumPixelsInplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_NumPixelsInplaneEditField.ValueDisplayFormat = '%11.10g';
            app.CrossplaneLabel_2 = uilabel(app.LinacEditTab);
            app.CrossplaneLabel_2.Text = 'Crossplane:';
            app.Linac_Edit_PixelDimsCrossplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_PixelDimsCrossplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_PixelDimsCrossplaneEditField.ValueDisplayFormat = '%11.10g';
            app.InplaneLabel_2 = uilabel(app.LinacEditTab);
            app.InplaneLabel_2.Text = 'Inplane:';
            app.Linac_Edit_PixelDimsInplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_PixelDimsInplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_PixelDimsInplaneEditField.ValueDisplayFormat = '%11.10g';
            app.LinacDimensionsLabel = uilabel(app.LinacEditTab);
            app.LinacDimensionsLabel.Text = 'Linac Dimensions:';
            app.cmLabel_3 = uilabel(app.LinacEditTab);
            app.cmLabel_3.Text = 'cm';
            app.SourcetoIsocentreDistanceLabel = uilabel(app.LinacEditTab);
            app.SourcetoIsocentreDistanceLabel.Text = 'Source to Isocentre Distance:';
            app.Linac_Edit_SourceToIsocentreEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_SourceToIsocentreEditField.Limits = [0 Inf];
            app.Linac_Edit_SourceToIsocentreEditField.ValueDisplayFormat = '%11.10g';
            app.EPIDtoIsocentreDistanceLabel = uilabel(app.LinacEditTab);
            app.EPIDtoIsocentreDistanceLabel.Text = 'EPID to Isocentre Distance:';
            app.Linac_Edit_EpidToIsocentreEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_EpidToIsocentreEditField.Limits = [0 Inf];
            app.Linac_Edit_EpidToIsocentreEditField.ValueDisplayFormat = '%11.10g';
            app.cmLabel_4 = uilabel(app.LinacEditTab);
            app.cmLabel_4.Text = 'cm';
            app.NotesLabel_2 = uilabel(app.LinacEditTab);
            app.NotesLabel_2.Text = 'Notes:';
            app.Linac_Edit_NotesTextArea = uitextarea(app.LinacEditTab);
            app.noteLinacLabel = uilabel(app.LinacEditTab);
            app.noteLinacLabel.Text = 'NOTE: To add, edit, or remove a commissioned photon energy for this linac';
            app.noteLinacLabel_2 = uilabel(app.LinacEditTab);
            app.noteLinacLabel_2.Text = 'create/save this linac, and then use the controls to the left to edit the energies.';
            app.EnergyEditTab = uitab(app.TabGroup);
            app.EnergyEditTab.Title = 'Energy Edit';
            app.EditCommissionPhotonBeamLabel = uilabel(app.EnergyEditTab);
            app.EditCommissionPhotonBeamLabel.Text = 'Edit/Commission Photon Beam';
            app.Label_4 = uilabel(app.EnergyEditTab);
            app.Label_4.Text = '_______________________________________________________________________________________________________________________________';
            app.Beam_Edit_SaveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_SaveButtonValueChanged, true);
            app.Beam_Edit_SaveButton.Text = 'Save';
            app.Beam_Edit_CancelButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CancelButtonValueChanged, true);
            app.Beam_Edit_CancelButton.Text = 'Cancel';
            app.NotesLabel_3 = uilabel(app.EnergyEditTab);
            app.NotesLabel_3.Text = 'Notes:';
            app.Beam_Edit_NotesTextArea = uitextarea(app.EnergyEditTab);
            app.MeVLabel = uilabel(app.EnergyEditTab);
            app.MeVLabel.Text = 'MeV';
            app.BeamEnergyLabel = uilabel(app.EnergyEditTab);
            app.BeamEnergyLabel.Text = 'Beam Energy:';
            app.Beam_Edit_BeamEnergyEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_BeamEnergyEditField.Limits = [0 Inf];
            app.Beam_Edit_BeamEnergyEditField.ValueDisplayFormat = '%11.10g';
            app.CommissioningStepsLabel = uilabel(app.EnergyEditTab);
            app.CommissioningStepsLabel.Text = 'Commissioning Steps:';
            app.ChooseFieldSizesPhantomWidthsandPhantomShiftsLabel = uilabel(app.EnergyEditTab);
            app.ChooseFieldSizesPhantomWidthsandPhantomShiftsLabel.Text = '1. Choose Field Sizes, Phantom Widths, and Phantom Shifts';
            app.FieldSizeslLabel = uilabel(app.EnergyEditTab);
            app.FieldSizeslLabel.Text = 'Field Sizes, l:';
            app.Beam_Edit_FieldSizesEditField = uieditfield(app.EnergyEditTab, 'text');
            app.FieldscansetasxyzorstartintervalendLabel = uilabel(app.EnergyEditTab);
            app.FieldscansetasxyzorstartintervalendLabel.Text = 'Fields can set as: "x, y, z" or "start:interval:end"';
            app.nxncmLabel = uilabel(app.EnergyEditTab);
            app.nxncmLabel.Text = 'n x n (cm)';
            app.sizesofthesquarefieldsthecollimatorswillbesettoLabel = uilabel(app.EnergyEditTab);
            app.sizesofthesquarefieldsthecollimatorswillbesettoLabel.Text = 'sizes of the square fields the collimators will be set to';
            app.cmLabel_5 = uilabel(app.EnergyEditTab);
            app.cmLabel_5.Text = 'cm';
            app.thewidththicknessofthesolidwaterphantomstobeusedLabel = uilabel(app.EnergyEditTab);
            app.thewidththicknessofthesolidwaterphantomstobeusedLabel.Text = 'the width/thickness of the solid water phantoms to be used';
            app.PhantomWidthswLabel = uilabel(app.EnergyEditTab);
            app.PhantomWidthswLabel.Text = 'Phantom Widths, w:';
            app.Beam_Edit_PhantomWidthsEditField = uieditfield(app.EnergyEditTab, 'text');
            app.cmLabel_6 = uilabel(app.EnergyEditTab);
            app.cmLabel_6.Text = 'cm';
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel = uilabel(app.EnergyEditTab);
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel.Text = 'the amount the centre of the solid water phantoms will be shifted';
            app.PhantomShiftsdLabel = uilabel(app.EnergyEditTab);
            app.PhantomShiftsdLabel.Text = 'Phantom Shifts, d:';
            app.Beam_Edit_PhantomShiftsEditField = uieditfield(app.EnergyEditTab, 'text');
            app.fromisocentred0isashiftupwardsd0isdownwardsLabel = uilabel(app.EnergyEditTab);
            app.fromisocentred0isashiftupwardsd0isdownwardsLabel.Text = 'from isocentre (d > 0 is a shift upwards, d < 0 is downwards) ';
            app.ChooseandcreatedirectoriestoholdcommissioningdataLabel = uilabel(app.EnergyEditTab);
            app.ChooseandcreatedirectoriestoholdcommissioningdataLabel.Text = '2. Choose and create directories to hold commissioning data';
            app.text_step2 = uilabel(app.EnergyEditTab);
            app.text_step2.Text = 'Select where you want to store the commissioning EPID data, and then press "Create Directories". At this point, the beam energy';
            app.test_Step2_2 = uilabel(app.EnergyEditTab);
            app.test_Step2_2.Text = 'will be added to the linac and saved. The folders will be named with the convention "[lXX][dYY][wZZ]", where XX is the';
            app.test_step2_3 = uilabel(app.EnergyEditTab);
            app.test_step2_3.Text = 'field size, YY is the phantom shift, and ZZ is the phantom width.';
            app.CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel = uilabel(app.EnergyEditTab);
            app.CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel.Text = '3. Collect the EPID data and place it within the appropriate directory';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4.Text = 'Deliver the set amount of MUs (see above) to each phantom configuration (field size, width, and shift), acquire the data, and place';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6.Text = 'the Dicoms in the matching directory.';
            app.LoadTissueMaximumRatioDataLabel = uilabel(app.EnergyEditTab);
            app.LoadTissueMaximumRatioDataLabel.Text = '4. Load Tissue-Maximum Ratio Data';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7.Text = 'Data should be in a Excel file, with NO HEADERS, with field sizes in the first column';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8.Text = 'and TMR values in the second column. This file may be deleted after this commissioning. ';
            app.CalculatecommissioningvaluesLabel = uilabel(app.EnergyEditTab);
            app.CalculatecommissioningvaluesLabel.Text = '6. Calculate commissioning values';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9.Text = 'Press "Calculate & Save" and wait for calculation to finish. EPID data Dicoms may be deleted after this commissioning.';
            app.Beam_Edit_CommissioningDataPathEditField = uieditfield(app.EnergyEditTab, 'text');
            app.Beam_Edit_CommissioningDataPathEditField.Editable = 'off';
            app.Beam_Edit_CommissioningDataPathSelectButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningDataPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningDataPathSelectButtonValueChanged, true);
            app.Beam_Edit_CommissioningDataPathSelectButton.Text = 'Select Path...';
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningDataDirectoriesCreateButtonValueChanged, true);
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton.Text = 'Create Directories';
            app.Label_5 = uilabel(app.EnergyEditTab);
            app.Label_5.Text = '_______________________________________________________________________________________________________________________________';
            app.Beam_Edit_CommissioningCalculateAndSaveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningCalculateAndSaveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningCalculateAndSaveButtonValueChanged, true);
            app.Beam_Edit_CommissioningCalculateAndSaveButton.Text = 'Calculate & Save';
            app.Beam_Edit_TmrDataPathEditField = uieditfield(app.EnergyEditTab, 'text');
            app.Beam_Edit_TmrDataPathEditField.Editable = 'off';
            app.Beam_Edit_TmrDataPathSelectButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TmrDataPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TmrDataPathSelectButtonValueChanged, true);
            app.Beam_Edit_TmrDataPathSelectButton.Text = 'Select File...';
            app.Beam_Edit_TmrDataLoadButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TmrDataLoadButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TmrDataLoadButtonValueChanged, true);
            app.Beam_Edit_TmrDataLoadButton.Text = 'Load';
            app.MULabel_2 = uilabel(app.EnergyEditTab);
            app.MULabel_2.Text = 'MU';
            app.DeliveredMUsLabel = uilabel(app.EnergyEditTab);
            app.DeliveredMUsLabel.Text = 'Delivered MUs:';
            app.Beam_Edit_DeliveredMUsEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_DeliveredMUsEditField.Limits = [0 Inf];
            app.Beam_Edit_DeliveredMUsEditField.ValueDisplayFormat = '%11.10g';
            app.Beam_Edit_RemoveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_RemoveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_RemoveButtonValueChanged, true);
            app.Beam_Edit_RemoveButton.Text = 'Remove';
            app.LoadTreatmentPlanningSystemRTFileLabel = uilabel(app.EnergyEditTab);
            app.LoadTreatmentPlanningSystemRTFileLabel.Text = '5. Load Treatment Planning System RT File';
            app.text_step5 = uilabel(app.EnergyEditTab);
            app.text_step5.Text = 'Data should be in a Excel file, with NO HEADERS, with field sizes in the first column';
            app.test_step5_2 = uilabel(app.EnergyEditTab);
            app.test_step5_2.Text = 'and TMR values in the second column. This file may be deleted after this commissioning. ';
            app.Beam_Edit_TmrDataPathEditField_2 = uieditfield(app.EnergyEditTab, 'text');
            app.Beam_Edit_TmrDataPathEditField_2.Editable = 'off';
            app.Beam_Edit_TmrDataPathSelectButton_2 = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TmrDataPathSelectButton_2.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TmrDataPathSelectButton_2ValueChanged, true);
            app.Beam_Edit_TmrDataPathSelectButton_2.Text = 'Select File...';
            app.Beam_Edit_TmrDataLoadButton_2 = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TmrDataLoadButton_2.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TmrDataLoadButton_2ValueChanged, true);
            app.Beam_Edit_TmrDataLoadButton_2.Text = 'Load';
            app.PatientDoseCalculationPanel = uipanel(app.RITEDosUIFigure);
            app.PatientDoseCalculationPanel.Title = 'Patient Dose Calculation';
            app.CTDataLoadPathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.CTDataLoadPathLabel.Text = 'CT Data Load Path:';
            app.DoseCalc_CTDataPathEditField = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_CTDataPathEditField.Editable = 'off';
            app.DoseCalc_CTDataPathLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_CTDataPathLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_CTDataPathLoadButtonValueChanged, true);
            app.DoseCalc_CTDataPathLoadButton.Text = 'Load...';
            app.DoseCalc_CalculateButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_CalculateButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_CalculateButtonValueChanged, true);
            app.DoseCalc_CalculateButton.Text = 'Calculate Dose';
            app.Label_6 = uilabel(app.PatientDoseCalculationPanel);
            app.Label_6.Text = '____________________________________________________________________';
            app.EPIDDataLoadPathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.EPIDDataLoadPathLabel.Text = 'EPID Data Load Path:';
            app.DoseCalc_EpidPathEditField = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_EpidPathEditField.Editable = 'off';
            app.DoseCalc_EpidPathLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_EpidPathLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_EpidPathLoadButtonValueChanged, true);
            app.DoseCalc_EpidPathLoadButton.Text = 'Load...';
            app.degreesLabel = uilabel(app.PatientDoseCalculationPanel);
            app.degreesLabel.Text = 'degrees';
            app.GantryAngleLabel = uilabel(app.PatientDoseCalculationPanel);
            app.GantryAngleLabel.Text = 'Gantry Angle:';
            app.DoseCalc_GantryAngleEditField = uieditfield(app.PatientDoseCalculationPanel, 'numeric');
            app.DoseCalc_GantryAngleEditField.Limits = [0 Inf];
            app.DoseCalc_GantryAngleEditField.ValueDisplayFormat = '%11.10g';
            app.NumberofFractionsLabel = uilabel(app.PatientDoseCalculationPanel);
            app.NumberofFractionsLabel.Text = 'Number of Fractions:';
            app.DoseCalc_NumFractionsEditField = uieditfield(app.PatientDoseCalculationPanel, 'numeric');
            app.DoseCalc_NumFractionsEditField.Limits = [0 Inf];
            app.DoseCalc_NumFractionsEditField.ValueDisplayFormat = '%.0f';
            app.Label_7 = uilabel(app.RITEDosUIFigure);
            app.Label_7.Text = '__________________________________________________________________________________________________________________________________________________________________________________________________________________________';
            app.DrStefanoPecaemailLabel = uilabel(app.RITEDosUIFigure);
            app.DrStefanoPecaemailLabel.Text = 'Dr. Stefano Peca (email)';
            app.DavidDeVriesemailLabel = uilabel(app.RITEDosUIFigure);
            app.DavidDeVriesemailLabel.Text = 'David DeVries (email)';
            app.ZachWhatmanemailLabel = uilabel(app.RITEDosUIFigure);
            app.ZachWhatmanemailLabel.Text = 'Zach Whatman (email)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RITE_Dos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RITEDosUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RITEDosUIFigure)
        end
    end
end